classdef Preliminary_design_V1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        AerodynamicsTab                 matlab.ui.container.Tab
        PropulsionTab                   matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        ConfigurationsPanel             matlab.ui.container.Panel
        VstallEditField                 matlab.ui.control.NumericEditField
        VstallEditFieldLabel            matlab.ui.control.Label
        SpanEfficiencyEditField         matlab.ui.control.NumericEditField
        SpanEfficiencyEditFieldLabel    matlab.ui.control.Label
        VmaxEditField                   matlab.ui.control.NumericEditField
        VmaxEditFieldLabel              matlab.ui.control.Label
        DensityEditField                matlab.ui.control.NumericEditField
        DensityEditFieldLabel           matlab.ui.control.Label
        ViscosityEditField              matlab.ui.control.NumericEditField
        ViscosityEditFieldLabel         matlab.ui.control.Label
        SgEditField                     matlab.ui.control.NumericEditField
        SgEditFieldLabel                matlab.ui.control.Label
        VCruiseEditField                matlab.ui.control.NumericEditField
        VCruiseEditFieldLabel           matlab.ui.control.Label
        VoEditField                     matlab.ui.control.NumericEditField
        VoEditFieldLabel                matlab.ui.control.Label
        VLOFEditField                   matlab.ui.control.NumericEditField
        VLOFEditFieldLabel              matlab.ui.control.Label
        MassEditField                   matlab.ui.control.NumericEditField
        MassEditFieldLabel              matlab.ui.control.Label
        OUTPUTPanel                     matlab.ui.container.Panel
        KGLabel_2                       matlab.ui.control.Label
        KGLabel                         matlab.ui.control.Label
        FlowButtonGroup                 matlab.ui.container.ButtonGroup
        TurbulentButton                 matlab.ui.control.RadioButton
        LaminarButton                   matlab.ui.control.RadioButton
        DynamicThrustEditField          matlab.ui.control.NumericEditField
        DynamicThrustEditFieldLabel     matlab.ui.control.Label
        StaticThrustEditField           matlab.ui.control.NumericEditField
        StaticThrustEditFieldLabel      matlab.ui.control.Label
        CruiseDragCoeffEditField        matlab.ui.control.NumericEditField
        CruiseDragCoeffEditFieldLabel   matlab.ui.control.Label
        TakeoffDragCoeffEditField       matlab.ui.control.NumericEditField
        TakeoffDragCoeffEditFieldLabel  matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        FuselagePanel                   matlab.ui.container.Panel
        MaxAreaEditFieldLabel           matlab.ui.control.Label
        EditField_5                     matlab.ui.control.NumericEditField
        WetAreaEditFieldLabel           matlab.ui.control.Label
        EditField_4                     matlab.ui.control.NumericEditField
        LengthEditFieldLabel            matlab.ui.control.Label
        EditField_3                     matlab.ui.control.NumericEditField
        HorizontalStabilizerPanel       matlab.ui.container.Panel
        AREditField_3                   matlab.ui.control.NumericEditField
        AREditField_3Label              matlab.ui.control.Label
        MACEditField_3                  matlab.ui.control.NumericEditField
        MACEditField_3Label             matlab.ui.control.Label
        ShEditField                     matlab.ui.control.NumericEditField
        ShEditFieldLabel                matlab.ui.control.Label
        XCEditField_3                   matlab.ui.control.NumericEditField
        XCEditField_3Label              matlab.ui.control.Label
        TCEditField_3                   matlab.ui.control.NumericEditField
        TCEditField_3Label              matlab.ui.control.Label
        VerticalStabilizerPanel         matlab.ui.container.Panel
        AREditField_2                   matlab.ui.control.NumericEditField
        AREditField_2Label              matlab.ui.control.Label
        MACEditField_2                  matlab.ui.control.NumericEditField
        MACEditField_2Label             matlab.ui.control.Label
        SvEditField                     matlab.ui.control.NumericEditField
        SvEditFieldLabel                matlab.ui.control.Label
        XCEditField_2                   matlab.ui.control.NumericEditField
        XCEditField_2Label              matlab.ui.control.Label
        TCEditField_2                   matlab.ui.control.NumericEditField
        TCEditField_2Label              matlab.ui.control.Label
        WingPanel                       matlab.ui.container.Panel
        AREditField                     matlab.ui.control.NumericEditField
        AREditFieldLabel                matlab.ui.control.Label
        MACEditField                    matlab.ui.control.NumericEditField
        MACEditFieldLabel               matlab.ui.control.Label
        SwEditField                     matlab.ui.control.NumericEditField
        SwEditFieldLabel                matlab.ui.control.Label
        XCEditField                     matlab.ui.control.NumericEditField
        XCEditFieldLabel                matlab.ui.control.Label
        TCEditField                     matlab.ui.control.NumericEditField
        TCEditFieldLabel                matlab.ui.control.Label
    end

    
    properties (Access = public)
        flow
        Swing
        mass
        Density
        K
%     %Total Drag
        CD_0
        CD_total
        CD_total_TO
%     %Thrust
        Required_dynamic
        Required_static
    end
  
    
    methods (Access = public)
        
        function Calc_drag(app)
            %%Variables
                app.mass=app.MassEditField.Value;
                app.Density=app.DensityEditField.Value;
                Vcr=app.VCruiseEditField.Value;
                Viscosity=app.ViscosityEditField.Value;
                Vstall=app.VstallEditField.Value;
            %Wing
                TC_wing=app.TCEditField.Value;
                XC_wing=app.XCEditField.Value;
                MAC_wing=app.MACEditField.Value;
                app.Swing=app.SwEditField.Value;
                AR_wing=app.AREditField.Value;
                span_eff=app.SpanEfficiencyEditField.Value;
            %TC_htail
                TC_htail=app.TCEditField_3.Value;
                XC_htail=app.XCEditField_3.Value;
                Shtail=app.ShEditField.Value;
                MAC_Htail=app.MACEditField_3.Value;
                AR_Htail=app.AREditField_3.Value;
            %Vtail
                Svtail=app.SvEditField.Value;
                TC_vtail=app.TCEditField_2.Value;
                XC_vtail=app.XCEditField_2.Value;
                MAC_Vtail=app.MACEditField_2.Value;
                AR_Vtail=app.AREditField_2.Value;
            %Fuselage
                fuselage_length=app.EditField_3.Value;
                fuselage_Amax=app.EditField_5.Value;
                fuselage_Swetted=app.EditField_4.Value;
            %% Drag calculation
            if(app.LaminarButton.Value==1)
                app.flow='L';
            else
                app.flow='T';
            end

            app.K=1/(pi*span_eff*AR_wing);

% Cruise Drag
            Drag_obj=Drag(app.flow,app.Swing,app.mass,Vcr,app.Density,Viscosity);
                 Induced_drag=Drag_obj.induced(app.K);
                 Wing_drag=Drag_obj.Wing(TC_wing,XC_wing,MAC_wing);
                 Vtail_drag=Drag_obj.Vtail(TC_vtail,XC_vtail,Svtail,MAC_Vtail);
                 Htail_drag=Drag_obj.Htail(TC_htail,XC_htail,Shtail,MAC_Htail);
                 Fuselage_drag=Drag_obj.Fuselage(fuselage_length,fuselage_Amax,fuselage_Swetted);
                 app.CD_0=Wing_drag+Vtail_drag+Htail_drag+Fuselage_drag;
                 CD_total_no_interference=Induced_drag+app.CD_0;
                 app.CD_total=CD_total_no_interference+0.15*CD_total_no_interference;
% Take off Drag                 
           Drag_obj=Drag(app.flow,app.Swing,app.mass,Vstall,app.Density,Viscosity);
                 Induced_drag=Drag_obj.induced(app.K);
                 Wing_drag=Drag_obj.Wing(TC_wing,XC_wing,MAC_wing);
                 Vtail_drag=Drag_obj.Vtail(TC_vtail,XC_vtail,Svtail,MAC_Vtail);
                 Htail_drag=Drag_obj.Htail(TC_htail,XC_htail,Shtail,MAC_Htail);
                 Fuselage_drag=Drag_obj.Fuselage(fuselage_length,fuselage_Amax,fuselage_Swetted);
                 CD_total_no_interference=Induced_drag+Wing_drag+Vtail_drag+Htail_drag+Fuselage_drag;
                 app.CD_total_TO=CD_total_no_interference+0.15*CD_total_no_interference;
        end
        
        function Calc_Thrust(app)
            VLOF=app.VLOFEditField.Value;
            Vmax=app.VmaxEditField.Value;
            WS=app.mass*9.81/app.Swing;
            Sg=app.SgEditField.Value;
            VL=app.VoEditField.Value;

            Thrust_obj=Thrust(VLOF,Vmax,app.CD_0,app.CD_total_TO,WS,app.K,Sg,app.Density,VL);
            app.Required_dynamic=app.mass*Thrust_obj.TW_R_D();
            app.Required_static=app.mass*Thrust_obj.TW_R_S();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            Calc_drag(app);
            Calc_Thrust(app);
            app.StaticThrustEditField.Value=app.Required_static;
            app.DynamicThrustEditField.Value=app.Required_dynamic;
            app.CruiseDragCoeffEditField.Value=app.CD_total;
            app.TakeoffDragCoeffEditField.Value=app.CD_total_TO;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.AerodynamicsTab = uitab(app.TabGroup);
            app.AerodynamicsTab.Title = 'Aerodynamics';
            app.PropulsionTab = uitab(app.TabGroup);
            app.PropulsionTab.Title = 'Propulsion';
            app.WingPanel = uipanel(app.PropulsionTab);
            app.WingPanel.Title = 'Wing';
            app.TCEditFieldLabel = uilabel(app.WingPanel);
            app.TCEditFieldLabel.Text = 'T/C';
            app.TCEditField = uieditfield(app.WingPanel, 'numeric');
            app.XCEditFieldLabel = uilabel(app.WingPanel);
            app.XCEditFieldLabel.Text = 'X/C';
            app.XCEditField = uieditfield(app.WingPanel, 'numeric');
            app.SwEditFieldLabel = uilabel(app.WingPanel);
            app.SwEditFieldLabel.Text = 'Sw';
            app.SwEditField = uieditfield(app.WingPanel, 'numeric');
            app.MACEditFieldLabel = uilabel(app.WingPanel);
            app.MACEditFieldLabel.Text = 'MAC';
            app.MACEditField = uieditfield(app.WingPanel, 'numeric');
            app.AREditFieldLabel = uilabel(app.WingPanel);
            app.AREditFieldLabel.Text = 'AR';
            app.AREditField = uieditfield(app.WingPanel, 'numeric');
            app.VerticalStabilizerPanel = uipanel(app.PropulsionTab);
            app.VerticalStabilizerPanel.Title = 'Vertical Stabilizer';
            app.TCEditField_2Label = uilabel(app.VerticalStabilizerPanel);
            app.TCEditField_2Label.Text = 'T/C';
            app.TCEditField_2 = uieditfield(app.VerticalStabilizerPanel, 'numeric');
            app.XCEditField_2Label = uilabel(app.VerticalStabilizerPanel);
            app.XCEditField_2Label.Text = 'X/C';
            app.XCEditField_2 = uieditfield(app.VerticalStabilizerPanel, 'numeric');
            app.SvEditFieldLabel = uilabel(app.VerticalStabilizerPanel);
            app.SvEditFieldLabel.Text = 'Sv';
            app.SvEditField = uieditfield(app.VerticalStabilizerPanel, 'numeric');
            app.MACEditField_2Label = uilabel(app.VerticalStabilizerPanel);
            app.MACEditField_2Label.Text = 'MAC';
            app.MACEditField_2 = uieditfield(app.VerticalStabilizerPanel, 'numeric');
            app.AREditField_2Label = uilabel(app.VerticalStabilizerPanel);
            app.AREditField_2Label.Text = 'AR';
            app.AREditField_2 = uieditfield(app.VerticalStabilizerPanel, 'numeric');
            app.HorizontalStabilizerPanel = uipanel(app.PropulsionTab);
            app.HorizontalStabilizerPanel.Title = 'Horizontal Stabilizer';
            app.TCEditField_3Label = uilabel(app.HorizontalStabilizerPanel);
            app.TCEditField_3Label.Text = 'T/C';
            app.TCEditField_3 = uieditfield(app.HorizontalStabilizerPanel, 'numeric');
            app.XCEditField_3Label = uilabel(app.HorizontalStabilizerPanel);
            app.XCEditField_3Label.Text = 'X/C';
            app.XCEditField_3 = uieditfield(app.HorizontalStabilizerPanel, 'numeric');
            app.ShEditFieldLabel = uilabel(app.HorizontalStabilizerPanel);
            app.ShEditFieldLabel.Text = 'Sh';
            app.ShEditField = uieditfield(app.HorizontalStabilizerPanel, 'numeric');
            app.MACEditField_3Label = uilabel(app.HorizontalStabilizerPanel);
            app.MACEditField_3Label.Text = 'MAC';
            app.MACEditField_3 = uieditfield(app.HorizontalStabilizerPanel, 'numeric');
            app.AREditField_3Label = uilabel(app.HorizontalStabilizerPanel);
            app.AREditField_3Label.Text = 'AR';
            app.AREditField_3 = uieditfield(app.HorizontalStabilizerPanel, 'numeric');
            app.FuselagePanel = uipanel(app.PropulsionTab);
            app.FuselagePanel.Title = 'Fuselage';
            app.EditField_3 = uieditfield(app.FuselagePanel, 'numeric');
            app.LengthEditFieldLabel = uilabel(app.FuselagePanel);
            app.LengthEditFieldLabel.Text = 'Length';
            app.EditField_4 = uieditfield(app.FuselagePanel, 'numeric');
            app.WetAreaEditFieldLabel = uilabel(app.FuselagePanel);
            app.WetAreaEditFieldLabel.Text = 'Wet Area';
            app.EditField_5 = uieditfield(app.FuselagePanel, 'numeric');
            app.MaxAreaEditFieldLabel = uilabel(app.FuselagePanel);
            app.MaxAreaEditFieldLabel.Text = 'Max Area';
            app.OUTPUTPanel = uipanel(app.PropulsionTab);
            app.OUTPUTPanel.Title = 'OUTPUT';
            app.CalculateButton = uibutton(app.OUTPUTPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.TakeoffDragCoeffEditFieldLabel = uilabel(app.OUTPUTPanel);
            app.TakeoffDragCoeffEditFieldLabel.Text = 'Take off Drag Coeff';
            app.TakeoffDragCoeffEditField = uieditfield(app.OUTPUTPanel, 'numeric');
            app.CruiseDragCoeffEditFieldLabel = uilabel(app.OUTPUTPanel);
            app.CruiseDragCoeffEditFieldLabel.Text = 'Cruise Drag Coeff';
            app.CruiseDragCoeffEditField = uieditfield(app.OUTPUTPanel, 'numeric');
            app.StaticThrustEditFieldLabel = uilabel(app.OUTPUTPanel);
            app.StaticThrustEditFieldLabel.Text = 'Static Thrust';
            app.StaticThrustEditField = uieditfield(app.OUTPUTPanel, 'numeric');
            app.DynamicThrustEditFieldLabel = uilabel(app.OUTPUTPanel);
            app.DynamicThrustEditFieldLabel.Text = 'Dynamic Thrust';
            app.DynamicThrustEditField = uieditfield(app.OUTPUTPanel, 'numeric');
            app.FlowButtonGroup = uibuttongroup(app.OUTPUTPanel);
            app.FlowButtonGroup.Title = 'Flow';
            app.LaminarButton = uiradiobutton(app.FlowButtonGroup);
            app.LaminarButton.Text = 'Laminar';
            app.LaminarButton.Value = true;
            app.TurbulentButton = uiradiobutton(app.FlowButtonGroup);
            app.TurbulentButton.Text = 'Turbulent';
            app.KGLabel = uilabel(app.OUTPUTPanel);
            app.KGLabel.Text = 'KG';
            app.KGLabel_2 = uilabel(app.OUTPUTPanel);
            app.KGLabel_2.Text = 'KG';
            app.ConfigurationsPanel = uipanel(app.PropulsionTab);
            app.ConfigurationsPanel.Title = 'Configurations';
            app.MassEditFieldLabel = uilabel(app.ConfigurationsPanel);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.ConfigurationsPanel, 'numeric');
            app.VLOFEditFieldLabel = uilabel(app.ConfigurationsPanel);
            app.VLOFEditFieldLabel.Text = 'VLOF';
            app.VLOFEditField = uieditfield(app.ConfigurationsPanel, 'numeric');
            app.VoEditFieldLabel = uilabel(app.ConfigurationsPanel);
            app.VoEditFieldLabel.Text = 'Vo';
            app.VoEditField = uieditfield(app.ConfigurationsPanel, 'numeric');
            app.VCruiseEditFieldLabel = uilabel(app.ConfigurationsPanel);
            app.VCruiseEditFieldLabel.Text = 'V Cruise';
            app.VCruiseEditField = uieditfield(app.ConfigurationsPanel, 'numeric');
            app.SgEditFieldLabel = uilabel(app.ConfigurationsPanel);
            app.SgEditFieldLabel.Text = 'Sg';
            app.SgEditField = uieditfield(app.ConfigurationsPanel, 'numeric');
            app.ViscosityEditFieldLabel = uilabel(app.ConfigurationsPanel);
            app.ViscosityEditFieldLabel.Text = 'Viscosity';
            app.ViscosityEditField = uieditfield(app.ConfigurationsPanel, 'numeric');
            app.DensityEditFieldLabel = uilabel(app.ConfigurationsPanel);
            app.DensityEditFieldLabel.Text = 'Density';
            app.DensityEditField = uieditfield(app.ConfigurationsPanel, 'numeric');
            app.VmaxEditFieldLabel = uilabel(app.ConfigurationsPanel);
            app.VmaxEditFieldLabel.Text = 'V max';
            app.VmaxEditField = uieditfield(app.ConfigurationsPanel, 'numeric');
            app.SpanEfficiencyEditFieldLabel = uilabel(app.ConfigurationsPanel);
            app.SpanEfficiencyEditFieldLabel.Text = 'Span Efficiency';
            app.SpanEfficiencyEditField = uieditfield(app.ConfigurationsPanel, 'numeric');
            app.VstallEditFieldLabel = uilabel(app.ConfigurationsPanel);
            app.VstallEditFieldLabel.Text = 'V stall';
            app.VstallEditField = uieditfield(app.ConfigurationsPanel, 'numeric');
            app.TextArea = uitextarea(app.PropulsionTab);
            app.TextArea.Value = {'Note : All units in SI'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Preliminary_design_V1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end